classdef calibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        InsertthenameoftheserialportcurrentlyinuseLabel  matlab.ui.control.Label
        sEditField                  matlab.ui.control.EditField
        sEditFieldLabel             matlab.ui.control.Label
        IterazionenSpinner          matlab.ui.control.Spinner
        IterazionenSpinnerLabel     matlab.ui.control.Label
        LearningRateEditField       matlab.ui.control.NumericEditField
        LearningRateEditFieldLabel  matlab.ui.control.Label
        SpecifythenumberofsampleweightsLabel  matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        NEditField                  matlab.ui.control.NumericEditField
        NLabel                      matlab.ui.control.Label
        CalibrationButton           matlab.ui.control.Button
        ComputethelinearregressionButton  matlab.ui.control.Button
        SavetheexperimentaldataintoatxtfileandacsvfileButton  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            value = app.NEditField.Value;
            assignin('base', 'N', value)
        end

        % Button pushed function: 
        % SavetheexperimentaldataintoatxtfileandacsvfileButton
        function SavetheexperimentaldataintoatxtfileandacsvfileButtonPushed(app, event)
            x = evalin('base', 'x');
            y = evalin('base', 'y');
            T = table(x, y, 'VariableNames', {'Known weight', 'Measured weight'});
            writetable(T, 'calibration.txt');
            writetable(T, 'calibration.csv');
        end

        % Button pushed function: ComputethelinearregressionButton
        function ComputethelinearregressionButtonPushed(app, event)
            x = evalin('base', 'x');
            y = evalin('base', 'y');
            coeff = polyfit(x, y, 1);
            calibration_factor = coeff(1);
            assignin('base', 'calibration_factor', calibration_factor)
            offset = coeff(2);
            assignin('base', 'offset', offset)
            z = polyval(coeff, x);
            assignin('base', 'z', z)
            err = z - x;
            err2 = norm(err, 2);
            assignin('base', 'err2', err2)

            prompt = 'Insert the current calibration factor: ';
            if app.IterazionenSpinner.Value ~= 0
                calibration_factor = input(prompt) + err2 * app.LearningRateEditField.Value;
            end

            T = table(calibration_factor, offset, err2);
            % Add data to the table UI Component
            app.UITable.Data = T;
            
            plot(app.UIAxes, x, y, 'go', 'LineWidth' , 1, ... 
                    'MarkerEdgeColor', 'k', ...
                    'MarkerFaceColor', 'g' ,...
                    'MarkerSize', 10);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'y')
            title(app.UIAxes, 'Linear Regression')
            hold(app.UIAxes, 'on');

            plot(app.UIAxes, x, z); 
            legend(app.UIAxes, 'Data', 'Resulting line')
            legend(app.UIAxes);
            legend(app.UIAxes, 'Location','best');
            hold(app.UIAxes, 'off');
        end

        % Button pushed function: CalibrationButton
        function CalibrationButtonPushed(app, event)
            N = evalin('base', 'N');
            measures = zeros(N, 5); %5 positions per object
            x = zeros(N, 1);
            y = x;
            
            serial_port = app.sEditField.Value;
            s = serial(serial_port, 'BaudRate', 115200);
            fopen(s);
            
            while fscanf(s) ~= "Setup done"
             
            end
            
            for i = 1 : N
                fprintf('\n');
                for j = 1 : 5
                    if j == 1
                        prompt = ['Insert known weight in grams, place the object in the' ...
                            ' middle of the scale and then press enter: '];
                        while true
                            T = input(prompt);
                            if isnumeric(str2double(T)) && ~isempty(T)
                              x(i) = T;
                              break
                            else
                              disp('Error, plese enter a correct value!')
                            end
                        end
                        fprintf('\n');
                    elseif j == 2
                        prompt = ['Place the object in the' ...
                            ' bottom left corner of the scale and then press enter: '];
                        input(prompt);
                    elseif j == 3
                        prompt = ['Place the object in the' ...
                            ' top left corner of the scale and then press enter: '];
                        input(prompt);
                    elseif j == 4
                        prompt = ['Place the object in the' ...
                            ' top right corner of the scale and then press enter: '];
                        input(prompt);
                    elseif j == 5
                        prompt = ['Place the object in the' ...
                            ' bottom right corner of the scale and then press enter: '];
                        input(prompt);
                    end
                    fprintf(s, 'a');
                    measures(i, j) = fscanf(s, '%f'); %converts the reading in a float number
                    fprintf('Serial value: %f\n', measures(i, j));
                end
            end
            
            fclose(s);
            delete(s);

            assignin('base', 'x', x)
            
            for i = 1 : N
                y(i) = mean(measures(i, :));
            end

            assignin('base', 'y', y)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Linear Regression')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SavetheexperimentaldataintoatxtfileandacsvfileButton = uibutton(app.UIFigure, 'push');
            app.SavetheexperimentaldataintoatxtfileandacsvfileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetheexperimentaldataintoatxtfileandacsvfileButtonPushed, true);
            app.SavetheexperimentaldataintoatxtfileandacsvfileButton.Text = 'Save the experimental data into a txt file and a csv file';
            app.ComputethelinearregressionButton = uibutton(app.UIFigure, 'push');
            app.ComputethelinearregressionButton.ButtonPushedFcn = createCallbackFcn(app, @ComputethelinearregressionButtonPushed, true);
            app.ComputethelinearregressionButton.Text = 'Compute the linear regression';
            app.CalibrationButton = uibutton(app.UIFigure, 'push');
            app.CalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationButtonPushed, true);
            app.CalibrationButton.Text = 'Calibration';
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Calibration Factor'; 'Offset'; 'Err2'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.SpecifythenumberofsampleweightsLabel = uilabel(app.UIFigure);
            app.SpecifythenumberofsampleweightsLabel.Text = 'Specify the number of sample weights';
            app.LearningRateEditFieldLabel = uilabel(app.UIFigure);
            app.LearningRateEditFieldLabel.Text = 'Learning Rate';
            app.LearningRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterazionenSpinnerLabel = uilabel(app.UIFigure);
            app.IterazionenSpinnerLabel.Text = 'Iterazione n.';
            app.IterazionenSpinner = uispinner(app.UIFigure);
            app.sEditFieldLabel = uilabel(app.UIFigure);
            app.sEditFieldLabel.Text = 's';
            app.sEditField = uieditfield(app.UIFigure, 'text');
            app.InsertthenameoftheserialportcurrentlyinuseLabel = uilabel(app.UIFigure);
            app.InsertthenameoftheserialportcurrentlyinuseLabel.Text = 'Insert the name of the serial port currently in use ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calibration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end