classdef eccentricity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        sEditField              matlab.ui.control.EditField
        sEditFieldLabel         matlab.ui.control.Label
        InsertthenameoftheserialportcurrentlyinuseLabel  matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        EccentricitytestButton  matlab.ui.control.Button
        SavetheexperimentaldataintoatxtfileandacsvfileButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: 
        % SavetheexperimentaldataintoatxtfileandacsvfileButton
        function SavetheexperimentaldataintoatxtfileandacsvfileButtonPushed(app, event)
            measures = evalin('base', 'measures');
            error = evalin('base', 'error');
            T = table(measures, error, 'VariableNames', {'Measured weight', 'Error'});
            writetable(T, 'eccentricity.txt');
            writetable(T, 'eccentricity.csv');
        end

        % Button pushed function: EccentricitytestButton
        function EccentricitytestButtonPushed(app, event)
            measures = zeros(5, 1);
            
            prompt = ['Insert the weight in grams of the object and' ...
                ' press enter: '];
            while true
                  T = input(prompt);
                  if isnumeric(str2double(T)) && ~isempty(T)
                      known_weigth = T;
                      break
                  else
                      disp('Error, plese enter a correct value!')
                  end
            end
            
            prompt = ['Place the object in the' ...
                ' middle of the scale and then press enter: '];
            
            serial_port = app.sEditField.Value;
            s = serial(serial_port, 'BaudRate', 115200);
            fopen(s);
            
            while fscanf(s) ~= "Setup done"
             
            end
            
            for i = 1 : 5
                if i == 2
                    prompt = ['Place the object in the' ...
                        ' bottom left corner of the scale and then press enter: '];
                elseif i == 3
                    prompt = ['Place the object in the' ...
                        ' top left corner of the scale and then press enter: '];
                elseif i == 4
                    prompt = ['Place the object in the' ...
                        ' top rigth corner of the scale and then press enter: '];
                elseif i == 5
                    prompt = ['Place the object in the' ...
                        ' bottom rigth corner of the scale and then press enter: '];
                end
                input(prompt);
                fprintf(s, 'a');
                measures(i) = fscanf(s, '%f'); %converts the reading in a float number
                fprintf('Serial value: %f\n', measures(i));
            end
            
            fclose(s);
            delete(s);

            mean1 = mean(measures);
            standard_deviation = std(measures);
            error = known_weigth - measures;

            T = table(standard_deviation, mean1);
            % Add data to the table UI Component
            app.UITable.Data = T;

            assignin('base', 'measures', measures)
            assignin('base', 'mean', mean1)
            assignin('base', 'standard_deviation', standard_deviation)
            assignin('base', 'error', error)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SavetheexperimentaldataintoatxtfileandacsvfileButton = uibutton(app.UIFigure, 'push');
            app.SavetheexperimentaldataintoatxtfileandacsvfileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetheexperimentaldataintoatxtfileandacsvfileButtonPushed, true);
            app.SavetheexperimentaldataintoatxtfileandacsvfileButton.Text = 'Save the experimental data into a txt file and a csv file';
            app.EccentricitytestButton = uibutton(app.UIFigure, 'push');
            app.EccentricitytestButton.ButtonPushedFcn = createCallbackFcn(app, @EccentricitytestButtonPushed, true);
            app.EccentricitytestButton.Text = 'Eccentricity test';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Standard Deviation'; 'Mean'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.InsertthenameoftheserialportcurrentlyinuseLabel = uilabel(app.UIFigure);
            app.InsertthenameoftheserialportcurrentlyinuseLabel.Text = 'Insert the name of the serial port currently in use ';
            app.sEditFieldLabel = uilabel(app.UIFigure);
            app.sEditFieldLabel.Text = 's';
            app.sEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eccentricity

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end