classdef hysteresis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        sEditField        matlab.ui.control.EditField
        sEditFieldLabel   matlab.ui.control.Label
        InsertthenameoftheserialportcurrentlyinuseLabel  matlab.ui.control.Label
        SpecifythenumberofsampleweightsLabel  matlab.ui.control.Label
        NEditField        matlab.ui.control.NumericEditField
        NLabel            matlab.ui.control.Label
        HysteresisButton  matlab.ui.control.Button
        SavetheexperimentaldataintoatxtfileandacsvfileButton  matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            value = app.NEditField.Value;
            assignin('base', 'N', value)
        end

        % Button pushed function: 
        % SavetheexperimentaldataintoatxtfileandacsvfileButton
        function SavetheexperimentaldataintoatxtfileandacsvfileButtonPushed(app, event)
            x = evalin('base', 'x');
            measures = evalin('base', 'measures');
            y = measures;
            T = table(x, y, 'VariableNames', {'Known weight', 'Measured weight'});
            writetable(T, 'hysteresis.txt');
            writetable(T, 'hysteresis.csv');
        end

        % Button pushed function: HysteresisButton
        function HysteresisButtonPushed(app, event)
            N = evalin('base', 'N');
            measures = zeros(2*N, 1);
            x = measures;
            
            serial_port = app.sEditField.Value;
            s = serial(serial_port, 'BaudRate', 115200);
            fopen(s);
            
            while fscanf(s) ~= "Setup done"
            
            end
            
            prompt = ['Insert known weight in grams, place the object ' ...
                'on the scale and press enter (ascending order): '];
            
            for i = 1 : 2 * N
                fprintf('\n');
                if i == (1 + N)
                    prompt = ['Insert known weight in grams, place the object ' ...
                        'on the scale and press enter (descending order): '];
                end
                        while true
                            T = input(prompt);
                            if isnumeric(str2double(T)) && ~isempty(T)
                              x(i) = T;
                              break
                            else
                              disp('Error, plese enter a correct value!')
                            end
                        end
                fprintf(s, 'a');
                measures(i) = fscanf(s, '%f'); %converts the reading in a float number
                fprintf('Serial value: %f\n', measures(i));
            end
            
            fclose(s);
            delete(s);

            assignin('base', 'x', x)
            assignin('base', 'measures', measures)
            
            ascending = measures(1:N);
            descending = measures((N+1):end);
            plot(app.UIAxes, x(1:N), ascending, 'o', x(N+1:end), descending, '*')
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            legend(app.UIAxes, 'Data', 'Resulting line')
            legend(app.UIAxes);
            legend(app.UIAxes, 'Location','best');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Hysteresis')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SavetheexperimentaldataintoatxtfileandacsvfileButton = uibutton(app.UIFigure, 'push');
            app.SavetheexperimentaldataintoatxtfileandacsvfileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetheexperimentaldataintoatxtfileandacsvfileButtonPushed, true);
            app.SavetheexperimentaldataintoatxtfileandacsvfileButton.Text = 'Save the experimental data into a txt file and a csv file';
            app.HysteresisButton = uibutton(app.UIFigure, 'push');
            app.HysteresisButton.ButtonPushedFcn = createCallbackFcn(app, @HysteresisButtonPushed, true);
            app.HysteresisButton.Text = 'Hysteresis';
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.SpecifythenumberofsampleweightsLabel = uilabel(app.UIFigure);
            app.SpecifythenumberofsampleweightsLabel.Text = 'Specify the number of sample weights';
            app.InsertthenameoftheserialportcurrentlyinuseLabel = uilabel(app.UIFigure);
            app.InsertthenameoftheserialportcurrentlyinuseLabel.Text = 'Insert the name of the serial port currently in use ';
            app.sEditFieldLabel = uilabel(app.UIFigure);
            app.sEditFieldLabel.Text = 's';
            app.sEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hysteresis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end