classdef noise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        sEditField                      matlab.ui.control.EditField
        sEditFieldLabel                 matlab.ui.control.Label
        InsertthenameoftheserialportcurrentlyinuseLabel  matlab.ui.control.Label
        SpecifythenumberofsamplesLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        NEditField                      matlab.ui.control.NumericEditField
        NLabel                          matlab.ui.control.Label
        NoisetestButton                 matlab.ui.control.Button
        SavetheexperimentaldataintoatxtfileandacsvfileButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            value = app.NEditField.Value;
            assignin('base', 'N', value)
        end

        % Button pushed function: 
        % SavetheexperimentaldataintoatxtfileandacsvfileButton
        function SavetheexperimentaldataintoatxtfileandacsvfileButtonPushed(app, event)
            n = evalin('base', 'n');
            T = table(n, 'VariableNames', {'Noise'});
            writetable(T, 'noise.txt');
            writetable(T, 'noise.csv');
        end

        % Button pushed function: NoisetestButton
        function NoisetestButtonPushed(app, event)
            N = evalin('base', 'N');
            n = zeros(N, 1);
            
            serial_port = app.sEditField.Value;
            s = serial(serial_port, 'BaudRate', 115200);
            fopen(s);
            
            while fscanf(s) ~= "Setup done"
            
            end
            
            for i = 1 : N
                fprintf(s, 'a');
                n(i) = fscanf(s, '%f'); %converts the reading in a float number 
                fprintf('Serial value: %f\n', n(i));
            end
            
            fclose(s);
            delete(s);

            mean1 = mean(n);
            st_d = std(n);

            T = table(st_d, mean1);
            app.UITable.Data = T;

            assignin('base', 'n', n)
            assignin('base', 'mean', mean1)
            assignin('base', 'standard_deviation', st_d)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SavetheexperimentaldataintoatxtfileandacsvfileButton = uibutton(app.UIFigure, 'push');
            app.SavetheexperimentaldataintoatxtfileandacsvfileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetheexperimentaldataintoatxtfileandacsvfileButtonPushed, true);
            app.SavetheexperimentaldataintoatxtfileandacsvfileButton.Text = 'Save the experimental data into a txt file and a csv file';
            app.NoisetestButton = uibutton(app.UIFigure, 'push');
            app.NoisetestButton.ButtonPushedFcn = createCallbackFcn(app, @NoisetestButtonPushed, true);
            app.NoisetestButton.Text = 'Noise test';
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Standard Deviation'; 'Mean'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.SpecifythenumberofsamplesLabel = uilabel(app.UIFigure);
            app.SpecifythenumberofsamplesLabel.Text = 'Specify the number of samples';
            app.InsertthenameoftheserialportcurrentlyinuseLabel = uilabel(app.UIFigure);
            app.InsertthenameoftheserialportcurrentlyinuseLabel.Text = 'Insert the name of the serial port currently in use ';
            app.sEditFieldLabel = uilabel(app.UIFigure);
            app.sEditFieldLabel.Text = 's';
            app.sEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = noise

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end