classdef repeatability < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        gLabel                    matlab.ui.control.Label
        InserttheweightofthesampleLabel  matlab.ui.control.Label
        mEditField                matlab.ui.control.NumericEditField
        mEditFieldLabel           matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        IsthetestpassedLamp       matlab.ui.control.Lamp
        IsthetestpassedLampLabel  matlab.ui.control.Label
        rangeEditField            matlab.ui.control.NumericEditField
        rangeEditFieldLabel       matlab.ui.control.Label
        InsertthepercentageofthereadingaccordingtothestandardLabel  matlab.ui.control.Label
        sEditField                matlab.ui.control.EditField
        sEditFieldLabel           matlab.ui.control.Label
        InsertthenameoftheserialportcurrentlyinuseLabel  matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        RepeatabilitytestButton   matlab.ui.control.Button
        SavetheexperimentaldataintoatxtfileandacsvfileButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: 
        % SavetheexperimentaldataintoatxtfileandacsvfileButton
        function SavetheexperimentaldataintoatxtfileandacsvfileButtonPushed(app, event)
            measures = evalin('base', 'measures');
            T = table(measures, 'VariableNames', {'Measures'});
            writetable(T, 'repeatability.txt');
            writetable(T, 'repeatability.csv');
        end

        % Button pushed function: RepeatabilitytestButton
        function RepeatabilitytestButtonPushed(app, event)
            measures = zeros(5, 1);
            input('Remove any object from the scale. ')
            
            serial_port = app.sEditField.Value;
            s = serial(serial_port, 'BaudRate', 115200);
            fopen(s);
            
            while fscanf(s) ~= "Setup done"
            
            end
            
            for i = 1 : 5
                fprintf(s, 't');
                input('Place the object on the scale. ')
                fprintf(s, 'a');
                measures(i, 1) = fscanf(s, '%f');
                fprintf('Serial value: %f\n', measures(i, 1));
                input('Remove the object from the scale. ')
            end
            
            fclose(s);
            delete(s);

            mean1 = mean(measures);
            standard_dev = std(measures);

            T = table(standard_dev, mean1);
            app.UITable.Data = T;

            if standard_dev <= (app.rangeEditField.Value*app.mEditField.Value)/100
                app.IsthetestpassedLamp.Color = 'green';
            else
                app.IsthetestpassedLamp.Color = 'red';
            end

            assignin('base', 'measures', measures)
            assignin('base', 'mean', mean1)
            assignin('base', 'standard_dev', standard_dev)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SavetheexperimentaldataintoatxtfileandacsvfileButton = uibutton(app.UIFigure, 'push');
            app.SavetheexperimentaldataintoatxtfileandacsvfileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetheexperimentaldataintoatxtfileandacsvfileButtonPushed, true);
            app.SavetheexperimentaldataintoatxtfileandacsvfileButton.Text = 'Save the experimental data into a txt file and a csv file';
            app.RepeatabilitytestButton = uibutton(app.UIFigure, 'push');
            app.RepeatabilitytestButton.ButtonPushedFcn = createCallbackFcn(app, @RepeatabilitytestButtonPushed, true);
            app.RepeatabilitytestButton.Text = 'Repeatability test';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Standard Deviation'; 'Mean'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.InsertthenameoftheserialportcurrentlyinuseLabel = uilabel(app.UIFigure);
            app.InsertthenameoftheserialportcurrentlyinuseLabel.Text = 'Insert the name of the serial port currently in use ';
            app.sEditFieldLabel = uilabel(app.UIFigure);
            app.sEditFieldLabel.Text = 's';
            app.sEditField = uieditfield(app.UIFigure, 'text');
            app.InsertthepercentageofthereadingaccordingtothestandardLabel = uilabel(app.UIFigure);
            app.InsertthepercentageofthereadingaccordingtothestandardLabel.Text = 'Insert the maximum percentage of the reading, according to the standard:';
            app.rangeEditFieldLabel = uilabel(app.UIFigure);
            app.rangeEditFieldLabel.Text = 'range';
            app.rangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.IsthetestpassedLampLabel = uilabel(app.UIFigure);
            app.IsthetestpassedLampLabel.Text = 'Is the test passed? ';
            app.IsthetestpassedLamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.mEditFieldLabel = uilabel(app.UIFigure);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.UIFigure, 'numeric');
            app.InserttheweightofthesampleLabel = uilabel(app.UIFigure);
            app.InserttheweightofthesampleLabel.Text = 'Insert the weight of the sample:';
            app.gLabel = uilabel(app.UIFigure);
            app.gLabel.Text = 'g';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = repeatability

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end